classdef UD_autoclave_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        UDATLabel                       matlab.ui.control.Label
        ClearButton_Inps                matlab.ui.control.StateButton
        VacuumBagSpecificationPanel     matlab.ui.container.Panel
        VacuumDurationminEditFieldLabel  matlab.ui.control.Label
        VD                              matlab.ui.control.EditField
        VacuumAppliedTimeminEditFieldLabel  matlab.ui.control.Label
        VAT                             matlab.ui.control.EditField
        VacuumPressurekPaEditFieldLabel  matlab.ui.control.Label
        VP                              matlab.ui.control.EditField
        AutoclaveSpecificationPanel     matlab.ui.container.Panel
        AutoclaveDurationminEditFieldLabel  matlab.ui.control.Label
        AD                              matlab.ui.control.EditField
        AutoclaveAppliedTimeminEditFieldLabel  matlab.ui.control.Label
        AAT                             matlab.ui.control.EditField
        AutoclavePressurekPaEditFieldLabel  matlab.ui.control.Label
        AP                              matlab.ui.control.EditField
        TemperatureProfileSpecificationPanel  matlab.ui.container.Panel
        SliderLabel                     matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        AHD2                            matlab.ui.control.EditField
        TemperatureDwellDurationminLabel_2  matlab.ui.control.Label
        AHRT2                           matlab.ui.control.EditField
        TemperatureRampDurationminLabel_2  matlab.ui.control.Label
        AHR2                            matlab.ui.control.EditField
        TemperatureRampCminLabel        matlab.ui.control.Label
        AHD1                            matlab.ui.control.EditField
        TemperatureDwellDurationminLabel  matlab.ui.control.Label
        AHRT1                           matlab.ui.control.EditField
        TemperatureRampDurationminLabel  matlab.ui.control.Label
        AHR1                            matlab.ui.control.EditField
        TemperatureRampCminEditFieldLabel  matlab.ui.control.Label
        MaterialsPanel                  matlab.ui.container.Panel
        NomexhoneycombcoreLabel         matlab.ui.control.Label
        HoneycombCoreLabel              matlab.ui.control.Label
        HenkelLoctiteEA9658AEROLabel    matlab.ui.control.Label
        AdhesiveFilmLabel               matlab.ui.control.Label
        HexcelHexPly8552SLabel          matlab.ui.control.Label
        PrepregFacesheetsLabel          matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        Fig1                            matlab.ui.control.UIAxes
        Fig2                            matlab.ui.control.UIAxes
        PredictionTab                   matlab.ui.container.Tab
        UDATLabel_2                     matlab.ui.control.Label
        ClearButton                     matlab.ui.control.StateButton
        TotalTimeEditField              matlab.ui.control.EditField
        TotalTimeEditFieldLabel         matlab.ui.control.Label
        PRVolumeEditField               matlab.ui.control.EditField
        PRVolumeEditFieldLabel          matlab.ui.control.Label
        EffectiveBondlinePorosity       matlab.ui.control.EditField
        EffectiveBondlinePorosityEditFieldLabel  matlab.ui.control.Label
        PrepregPorosity                 matlab.ui.control.EditField
        PrepregPorosityEditFieldLabel   matlab.ui.control.Label
        AdhesivePorosity                matlab.ui.control.EditField
        AdhesivePorosityEditFieldLabel  matlab.ui.control.Label
        MaxFiberVolumeFraction          matlab.ui.control.EditField
        MaxFiberVolumeFractionEditFieldLabel  matlab.ui.control.Label
        StartSimulationButton           matlab.ui.control.StateButton
        Fig4                            matlab.ui.control.UIAxes
        Fig3                            matlab.ui.control.UIAxes
        OptimizationTab                 matlab.ui.container.Tab
        Niter                           matlab.ui.control.EditField
        NumberofIterationsLabel         matlab.ui.control.Label
        UDATLabel_3                     matlab.ui.control.Label
        CostFunctionWeightsPanel        matlab.ui.container.Panel
        w3                              matlab.ui.control.EditField
        TotalCuringTimeLabel            matlab.ui.control.Label
        w2                              matlab.ui.control.EditField
        BondlinePorosityLabel           matlab.ui.control.Label
        w1                              matlab.ui.control.EditField
        FacesheetConsolidationLabel     matlab.ui.control.Label
        OptimalCureCyclePanel           matlab.ui.container.Panel
        OptAutoclaveDur                 matlab.ui.control.EditField
        AutoclaveDurationminLabel       matlab.ui.control.Label
        OptAutoclaveStart               matlab.ui.control.EditField
        AutoclaveStartTimeminLabel      matlab.ui.control.Label
        OptAutoclavePressure            matlab.ui.control.EditField
        AutoclavePressurekPaLabel       matlab.ui.control.Label
        OptVacuumDur                    matlab.ui.control.EditField
        VacuumDurationminLabel          matlab.ui.control.Label
        OptVacuumStart                  matlab.ui.control.EditField
        VacuumStartTimeminLabel         matlab.ui.control.Label
        OptVacuumPressure               matlab.ui.control.EditField
        VacuumPressurekPaLabel          matlab.ui.control.Label
        OptTemperatureDwell2            matlab.ui.control.EditField
        TemperatureDwellDurationminLabel_5  matlab.ui.control.Label
        OptTemperatureRampDur2          matlab.ui.control.EditField
        TemperatureRampDurationminLabel_7  matlab.ui.control.Label
        OptTemperatureRamp2             matlab.ui.control.EditField
        TemperatureRampCminLabel_3      matlab.ui.control.Label
        OptTemperatureDwell1            matlab.ui.control.EditField
        TemperatureDwellDurationminLabel_3  matlab.ui.control.Label
        OptTemperatureRampDur1          matlab.ui.control.EditField
        TemperatureRampDurationminLabel_3  matlab.ui.control.Label
        OptTemperatureRamp1             matlab.ui.control.EditField
        TemperatureRampCminLabel_2      matlab.ui.control.Label
        OptimalOutputsPanel             matlab.ui.container.Panel
        OptimalCureDegree_AD            matlab.ui.control.EditField
        CureDegreeAdhesiveLabel         matlab.ui.control.Label
        OptimalCureDegree_PR            matlab.ui.control.EditField
        CureDegreePrepregLabel          matlab.ui.control.Label
        OptimalTotalTime                matlab.ui.control.EditField
        TotalTimeEditField_2Label       matlab.ui.control.Label
        OptimalMaxFiberVolumeFraction   matlab.ui.control.EditField
        MaxFiberVolumeFractionLabel     matlab.ui.control.Label
        OptimalEffectiveBondlinePorosity  matlab.ui.control.EditField
        EffectiveBondlinePorosityLabel  matlab.ui.control.Label
        ClearButton_Opt                 matlab.ui.control.StateButton
        StartOptimizationButton         matlab.ui.control.StateButton
        FacilityConstraintPanel         matlab.ui.container.Panel
        MaxHeatRate                     matlab.ui.control.EditField
        MaxheatrateCminLabel            matlab.ui.control.Label
        MaxAutTemperature               matlab.ui.control.EditField
        MaxautoclavetemperatureCLabel   matlab.ui.control.Label
        MaxAutPressure                  matlab.ui.control.EditField
        MaxautoclavepressurekPaLabel    matlab.ui.control.Label
        Fig6                            matlab.ui.control.UIAxes
        Fig5                            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = public)

        function [alpha_9658,visc_9658] = Henkel_9658(app,t,T)
            t=transpose(t);
            T=transpose(T);
            % 9658 cure kinetics
            alpha_9658 = zeros(length(t),1);
            dadt_9658 = zeros(length(t),1);
            alpha_9658(1) = .001;             % alpha naught
            R = 8.3144598;
            A = 2266775.149;
            Ea = 74194.39762;
            m = 0.590429547;
            n = 1.874522003;
            C = 146.7856265;
            a_c0 = 0.039202958;
            a_cT = 0.007;
            for i = 1:length(alpha_9658)-1
                K = A*exp(-Ea/(R*(T(i)+273.15)));
                num = alpha_9658(i)^m*(1-alpha_9658(i))^n;
                denom = 1+exp(C*(alpha_9658(i)-(a_c0+(a_cT*(T(i)+273.15)))));
                dadt_9658(i) = K*num/denom;
                alpha_9658(i+1) = alpha_9658(i) + dadt_9658(i)*(t(i+1)-t(i));
            end

            % 9658 viscosity
            A1 = 2.04e-17;
            A2 = 2.47e-8;
            E1_R = 1.51e4;
            E2_R = 7.92e3;
            A = 18.46884668;
            B = -13.26957908;
            C = -10.65504657;
            a_gel = 0.714785;

            u1 = A1.*exp(E1_R./(T+273.15));
            u2 = A2.*exp(E2_R./(T+273.15)).*(a_gel./(a_gel-alpha_9658)).^(A+B*alpha_9658+C*alpha_9658.^2);
            for i = 1:length(T)
                if alpha_9658(i) > a_gel
                    visc_9658(i) = 1e7;
                else
                    visc_9658(i) = u1(i)+u2(i);
                end
            end

        end
        
        function [Cost]=ObjectiveFunction(app,Inputs,w,tmax)
            
            Outputs=MachineLearning(app,Inputs);
           
            Cost=w(1)*(59-Outputs(1))/(59-55) +w(2)*Outputs(4)/100+w(3)*Outputs(6)/tmax;
            
            maxTime=Inputs(2)+Inputs(3)+Inputs(5)+Inputs(6);
            
            
            [Time,Temperature,~,~]=CureCycle(app,Inputs);
            [doc_AD,~]=Henkel_9658(app,Time,Temperature.Profile);
            [doc_PR,~]=Hexcel_8552(app,Time,Temperature.Profile);
            
            if Inputs(8)+Inputs(9)>maxTime
                Cost=5;
            elseif Inputs(11)+Inputs(12)>maxTime
                Cost=5;
            end
            
            
            if doc_AD(end)<0.5
                Cost=5;
            elseif doc_PR(end)<0.5
                Cost=5;
            end
            
        end

        function[Outputs]=MachineLearning(app,Inputs)
            %pe=pyenv;

            VF=py.script.predict_fiber_volume_fraction(Inputs);
            AD_por=py.script.predict_AD_prosity(Inputs);
            PR_por=py.script.predict_PR_prosity(Inputs);
            Eff_por=py.script.predict_effective_porosity(Inputs);
            PR_vol=py.script.predict_PR_Volume(Inputs);
            tot_time=Inputs(2)+Inputs(3)+Inputs(5)+Inputs(6);

            Outputs=[VF,AD_por,PR_por,Eff_por,PR_vol,tot_time];
        end

        function [Time,Temperature,Vac,Auto] = CureCycle(app,inps)
            
            Temperature.HR1=inps(1);
            Temperature.HRT1=inps(2);
            Temperature.HD1=inps(3);
            Temperature.HR2=inps(4);
            Temperature.HRT2=inps(5);
            Temperature.HD2=inps(6);
            
            Vac.Pressure=inps(7)*1000;
            Vac.A_Time= inps(8);
            Vac.VD=inps(9);
            Vac.R_Time= Vac.A_Time+Vac.VD;
            
            Auto.Pressure=inps(10)*1000;
            Auto.A_Time=inps(11);
            Auto.AD=inps(12);
            Auto.R_Time=Auto.A_Time+Auto.AD;

            T0=20;          % Initial Temperature
            
            Patm=101325;
            
            if app.Slider.Value==1
                
                T.Ramp1=[T0:(Temperature.HR1/60):T0+(Temperature.HR1*Temperature.HRT1)];
                t.Ramp1=[0:1:Temperature.HRT1*60];
    
                T.Dwell1=(T0+Temperature.HR1*Temperature.HRT1)*ones(1,Temperature.HD1*60-1);
                t.Dwell1=[Temperature.HRT1*60+1:1:(Temperature.HRT1+Temperature.HD1)*60-1];
                Temperature.Profile=[T.Ramp1,T.Dwell1];
                Time=[t.Ramp1,t.Dwell1];
                Total_Time=Temperature.HRT1+Temperature.HD1;

                V1=Patm*ones(1,Vac.A_Time*60);
                V2=Vac.Pressure*ones(1,Vac.VD*60);
                V3=Patm*ones(1,(Total_Time-Vac.R_Time)*60);
                Vac.Profile=[V1,V2,V3];
                clear V1 V1T V2 V2T V3 V3T

                A1=Patm*ones(1,Auto.A_Time*60);
                A2=Auto.Pressure*ones(1,Auto.AD*60);
                A3=Patm*ones(1,(Total_Time-Auto.R_Time)*60);
                Auto.Profile=[A1,A2,A3];
                clear A1 A2 A3


            elseif app.Slider.Value==2
                T.Ramp1=[T0:(Temperature.HR1/60):T0+(Temperature.HR1*Temperature.HRT1)];
                t.Ramp1=[0:1:Temperature.HRT1*60];
    
                T.Dwell1=(T0+Temperature.HR1*Temperature.HRT1)*ones(1,Temperature.HD1*60-1);
                t.Dwell1=[Temperature.HRT1*60+1:1:(Temperature.HRT1+Temperature.HD1)*60-1];
                Temperature.Profile=[T.Ramp1,T.Dwell1];
                Time=[t.Ramp1,t.Dwell1];
                Total_Time=Temperature.HRT1+Temperature.HD1;
                
                T.Ramp2=[T0+Temperature.HR1*Temperature.HRT1:Temperature.HR2/60:...
                    T0+Temperature.HR1*Temperature.HRT1+Temperature.HR2*Temperature.HRT2];
                t.Ramp2=[(Temperature.HRT1+Temperature.HD1)*60:1:(Temperature.HRT1+Temperature.HD1+Temperature.HRT2)*60];

                T.Dwell2=(T0+Temperature.HR1*Temperature.HRT1+Temperature.HR2*Temperature.HRT2)...
                    *ones(1,Temperature.HD2*60);
                t.Dwell2=[(Temperature.HRT1+Temperature.HD1+Temperature.HRT2)*60+1:1:...
                    (Temperature.HRT1+Temperature.HD1+Temperature.HRT2+Temperature.HD2)*60];
                Temperature.Profile=[T.Ramp1,T.Dwell1,T.Ramp2,T.Dwell2];
                Time=[t.Ramp1,t.Dwell1,t.Ramp2,t.Dwell2];
                Total_Time=Temperature.HRT1+Temperature.HD1+Temperature.HRT2+Temperature.HD2;

                V1=Patm*ones(1,Vac.A_Time*60);
                V2=Vac.Pressure*ones(1,Vac.VD*60+1);
                V3=Patm*ones(1,(Total_Time-Vac.R_Time)*60);
                Vac.Profile=[V1,V2,V3];
                clear V1 V1T V2 V2T V3 V3T

                A1=Patm*ones(1,Auto.A_Time*60);
                A2=Auto.Pressure*ones(1,Auto.AD*60+1);
                A3=Patm*ones(1,(Total_Time-Auto.R_Time)*60);
                Auto.Profile=[A1,A2,A3];
                clear A1 A2 A3

            end

        end

        function [Alpha,Viscosity] = Hexcel_8552(app,Time, Temperature)
            %% Degree of Cure
            % Constants
            xc1.K=153900.5;
            xc1.Ea=64929.5;
            xc1.L=2.347;
            xc1.r=1;
            xc1.m=0;
            xc1.n2=1;
            xc1.b=0.1594;
            xc1.n=1.413;
            %----------
            xi2.K=1000;
            xi2.Ea=0;
            xi2.L=0;
            xi2.r=1;
            xi2.m=0;
            xi2.n2=0;
            xi2.b=1;
            xi2.n=0;
            %----------
            xc2.K=1000;
            xc2.Ea=0;
            xc2.L=0;
            xc2.r=1;
            xc2.m=0;
            xc2.n2=0;
            xc2.b=1;
            xc2.n=0;
            %----------
            xe.K=3.963e11;
            xe.Ea=133168.3;
            xe.L=1.029;
            xe.r=1;
            xe.m=0;
            xe.n2=1;
            xe.b=0;
            xe.n=5.586;
            %----------
            Kd0=4;
            B=0.21;
            a1=4.8e-4;
            a2=4.8e-4;
            Tga1=0;
            Tga2=100;
            b1=0.021;
            b2=0.031;
            Tgb1=120;
            Tgb2=195;
            Tg0=-7;
            Tginf=250;
            Lambda=0.78;
            R=8.3145;

            Alpha=zeros(size(Time));
            Alpha(1)=0.001;

            for i=1:length(Alpha)-1
                %% Tg
                Tg=Tg0+(Lambda*Alpha(i)*(Tginf-Tg0))/(1-Alpha(i)*(1-Lambda));
                %% af / bf
                Sa=(a2-a1)/(Tga2-Tga1);
                Ca=a2-Sa*Tga2;
                if Tg<Tga1
                    af=a1;
                elseif Tg<Tga2 && Tg>Tga1
                    af=Sa*Tg+Ca;
                elseif Tg>Tga2
                    af=a2;
                end
                Sb=(b2-b1)/(Tgb2-Tgb1);
                Cb=b2-Sb*Tgb2;
                if Tg<Tgb1
                    bf=b1;
                elseif Tg<Tgb2 && Tg>Tgb1
                    bf=Sb*Tg+Cb;
                elseif Tg>Tgb2
                    bf=b2;
                end
                f=af*(Temperature(i)-Tg)+bf;
                %% Xd
                Xddt=Kd0*exp(-B/f);
                %% Xc1
                Xc1dt=xc1.K*(exp(-xc1.Ea/(R*(Temperature(i)+273.15))))*...
                    ((1-Alpha(i))^xc1.L)*(((1/xc1.r)-Alpha(i))^xc1.m)*(((Alpha(i)^xc1.n2)+xc1.b)^xc1.n);
                Xi2dt=xi2.K*(exp(-xi2.Ea/(R*(Temperature(i)+273.15))))*...
                    ((1-Alpha(i))^xi2.L)*(((1/xi2.r)-Alpha(i))^xi2.m)*(((Alpha(i)^xi2.n2)+xi2.b)^xi2.n);
                Xc2dt= xc2.K*(exp(-xc2.Ea/(R*(Temperature(i)+273.15))))*...
                    ((1-Alpha(i))^xc2.L)*(((1/xc2.r)-Alpha(i))^xc2.m)*(((Alpha(i)^xc2.n2)+xc2.b)^xc2.n);
                Xedt= xe.K*(exp(-xe.Ea/(R*(Temperature(i)+273.15))))*...
                    ((1-Alpha(i))^xe.L)*(((1/xe.r)-Alpha(i))^xe.m)*(((Alpha(i)^xe.n2)+xe.b)^xe.n);
                %% Xk
                Xkdt=(1/((1/Xc1dt)+(1/(Xi2dt+Xc2dt))))+Xedt;
                %% X dot
                XDot=1/(((1/Xkdt)+(1/Xddt)));
                Alpha(i+1)=Alpha(i)+(Time(i+1)-Time(i))*(XDot);
            end

            %% Viscosity
            % Constants
            u01=7.5e-11;
            u02=4.81e-2;
            E1=81908;
            E2=13228;
            xg=0.545;
            xg=0.47;
            A=2.466;
            B=0;
            C=0;
            umax=1e6;
            for i = 1:length(Temperature)
                if Alpha(i) > xg
                    Viscosity(i) = umax;
                else
                    U1=u01*exp(E1/(R*(Temperature(i)+273.15)));
                    U2=u02*exp(E2/(R*(Temperature(i)+273.15)))*((xg/(xg-Alpha(i)))^(A+B*Alpha(i)));
                    Viscosity(i)=U1+ U2;
                end
            end


        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if app.Slider.Value==1
                app.AHR2.Editable=0;
                app.AHD2.Editable=0;
                app.AHRT2.Editable=0;
                app.AHR2.Enable=0;
                app.AHD2.Enable=0;
                app.AHRT2.Enable=0;
                app.AHR2.Value=num2str(0);
                app.AHD2.Value=num2str(0);
                app.AHRT2.Value=num2str(0);
            else
                app.AHR2.Editable=1;
                app.AHD2.Editable=1;
                app.AHRT2.Editable=1;
                app.AHR2.Enable=1;
                app.AHD2.Enable=1;
                app.AHRT2.Enable=1;


            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.Fig1.reset;
            app.Fig2.reset;
            clear Temperature T t Time Total_Time Vac Auto

            Temperature.HR1=str2double(app.AHR1.Value);
            Temperature.HRT1=str2double(app.AHRT1.Value);
            Temperature.HD1=str2double(app.AHD1.Value);
            Temperature.HR2=str2double(app.AHR2.Value);
            Temperature.HRT2=str2double(app.AHRT2.Value);
            Temperature.HD2=str2double(app.AHD2.Value);
            
            Vac.Pressure=str2double(app.VP.Value);
            Vac.A_Time= str2double(app.VAT.Value);
            Vac.VD=str2double(app.VD.Value);
            
            Auto.Pressure=str2double(app.AP.Value);
            Auto.A_Time=str2double(app.AAT.Value);
            Auto.AD=str2double(app.AD.Value);

            in=[Temperature.HR1,Temperature.HRT1,Temperature.HD1,Temperature.HR2,Temperature.HRT2,Temperature.HD2,Vac.Pressure,Vac.A_Time,Vac.VD,Auto.Pressure,Auto.A_Time,Auto.AD];
            
            Total_TIME.T=Temperature.HRT1+Temperature.HD1+Temperature.HRT2+Temperature.HD2;
            Total_TIME.V=str2double(app.VAT.Value)+str2double(app.VD.Value);
            Total_TIME.A=str2double(app.AAT.Value)+str2double(app.AD.Value);

            if Total_TIME.T<Total_TIME.V || Total_TIME.T<Total_TIME.A
                opts = struct('WindowStyle','replace',...
                    'Interpreter','tex');
                f= errordlg('\bf Temperature Total Time \rm  Does not Match \bf Pressure Total Time'...
                    ,'File Error', opts);
                %                 set(f,'Position',[550.000  500.0000  500.00   100.2500],...
                %                     'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom')


            end
            
            [Time,Temperature,Vac,Auto]=CureCycle(app,in);

            app.Fig1.XLim=[0,max(Time)/60];
            app.Fig1.YLim=[0,max(Temperature.Profile)];
            plot (app.Fig1,Time/60,Temperature.Profile,"LineWidth",2,"Color",'k')
            ylabel(app.Fig1,'Temperature [C]','FontSize', 14);
            xlabel(app.Fig1,'Time [min]','FontSize', 14);
            app.Fig1.XGrid='on'
            app.Fig1.YGrid='on'
            app.Fig1.YLim=[20,max(Temperature.Profile)+10];
            app.Fig1.XLim=[0,max(Time)/60];


            app.Fig2.reset;

            app.Fig2.XLim=[0,max(Time)/60];
            app.Fig2.YLim=[0,400];
            plot(app.Fig2,Time/60,Vac.Profile/1000,'LineWidth',2,'Color','k')
            hold(app.Fig2, 'on')
            ylabel(app.Fig2,'Pressure [kPa]','FontSize', 14);
            xlabel(app.Fig2,'Time [min]','FontSize', 14);
            plot(app.Fig2,Time/60,Auto.Profile/1000,'LineWidth',2,'Color','b')
            legend(app.Fig2, 'Vacuum Bag','Autoclave','Location','northeast','FontSize', 12)
            app.Fig2.XLim=[0,max(Time)/60];
            app.Fig2.YLim=[0,400];
            app.Fig2.XGrid='on'
            app.Fig2.YGrid='on'
            hold(app.Fig2, 'off')


        end

        % Value changed function: StartSimulationButton
        function StartSimulationButtonValueChanged(app, event)
            Temperature.HR1=str2double(app.AHR1.Value);
            Temperature.HRT1=str2double(app.AHRT1.Value);
            Temperature.HD1=str2double(app.AHD1.Value);
            Temperature.HR2=str2double(app.AHR2.Value);
            Temperature.HRT2=str2double(app.AHRT2.Value);
            Temperature.HD2=str2double(app.AHD2.Value);
            Vac.Pressure=str2double(app.VP.Value)/100;
            Vac.A_Time= str2double(app.VAT.Value);
            Vac.VD=str2double(app.VD.Value);
            Auto.Pressure=str2double(app.AP.Value)/100;
            Auto.A_Time=str2double(app.AAT.Value);
            Auto.AD=str2double(app.AD.Value);

            Inputs=[Temperature.HR1,Temperature.HRT1,Temperature.HD1,Temperature.HR2,Temperature.HRT2,Temperature.HD2,Vac.Pressure,Vac.A_Time,Vac.VD,Auto.Pressure,Auto.A_Time,Auto.AD];
            %------------------------- Cure Kinetics -------------------
            [Time,Temperature,~,~] =CureCycle(app,Inputs);
            t=Time;
            T=Temperature.Profile;

            %% Adhesive
            [Alpha_9658,Vic_9658]=Henkel_9658(app, t, T);
            yyaxis(app.Fig3,'left')
            ylabel(app.Fig3,'Viscosity [Pa.S]','FontSize', 14);
            xlabel(app.Fig3,'Time [min]','FontSize', 14);
            app.Fig3.YTick = ([.01 .1 1 10 100 1000 10000 1e5 1e6 1e7]);
            app.Fig3.YScale='log';
            plot(app.Fig3,Time/60,Vic_9658,'LineWidth',2,'Color','r')
            app.Fig3.YLim=([0 1e7]);

            yyaxis(app.Fig3,'right')
            ylabel(app.Fig3,'Degree of Cure','FontSize', 14);
            plot(app.Fig3,Time/60,Alpha_9658,'LineWidth',2,'Color','b')
            app.Fig3.YLim=([0 1]);
            app.Fig3.XGrid='on'
            app.Fig3.YGrid='on'
            hold(app.Fig3, 'off')


            %
            %             %% Prepreg
            [Alpha_8552,Vic_8552]=Hexcel_8552(app, t, T);
            yyaxis(app.Fig4,'right')
            ylabel(app.Fig4,'Degree of Cure','FontSize', 14);
            plot(app.Fig4,Time/60,Alpha_8552,'LineWidth',2,'Color','b')
            app.Fig4.YLim=([0 1]);
            app.Fig4.XGrid='on'
            app.Fig4.YGrid='on'


            yyaxis(app.Fig4,'left')
            ylabel(app.Fig4,'Viscosity [Pa.S]','FontSize', 14);
            xlabel(app.Fig4,'Time [min]','FontSize', 14);
            app.Fig4.YTick = ([.01 .1 1 10 100 1000 10000 1e5 1e6]);
            app.Fig4.YScale='log';
            plot(app.Fig4,Time/60,Vic_8552,'LineWidth',2,'Color','r')
            app.Fig4.YLim=([0 1e6]);
            hold(app.Fig4, 'off')


            %% Prediction            
            Outputs=MachineLearning(app,Inputs);

            app.MaxFiberVolumeFraction.Value=num2str(Outputs(1));
            app.AdhesivePorosity.Value=num2str(Outputs(2));
            app.PrepregPorosity.Value=num2str(Outputs(3));
            app.EffectiveBondlinePorosity.Value=num2str(Outputs(4));
            app.PRVolumeEditField.Value=num2str(Outputs(5));
            app.TotalTimeEditField.Value=num2str(Outputs(6));


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1038, 1038};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ClearButton
        function ClearButtonValueChanged(app, event)
            yyaxis(app.Fig4,'left')
            cla(app.Fig4)
            yyaxis(app.Fig4,'right')
            cla(app.Fig4)

            yyaxis(app.Fig3,'left')
            cla(app.Fig3)
            yyaxis(app.Fig3,'right')
            cla(app.Fig3)

            app.MaxFiberVolumeFraction.Value='';
            app.AdhesivePorosity.Value='';
            app.PrepregPorosity.Value='';
            app.EffectiveBondlinePorosity.Value='';
            app.PRVolumeEditField.Value='';
            app.TotalTimeEditField.Value='';

        end

        % Value changed function: StartOptimizationButton
        function StartOptimizationButtonValueChanged(app, event)

            %facility constrains
            MaxTime=str2double(app.AHRT1.Value)+str2double(app.AHD1.Value)+str2double(app.AHRT2.Value)+str2double(app.AHD2.Value);
            MaxPressure=str2double(app.MaxAutPressure.Value)/100;
            MaxTemperature=str2double(app.MaxAutTemperature.Value);
            MaxHR=str2double(app.MaxHeatRate.Value);
            
            lb=[1,1,1,1,1,1,0,1,1,1,1,1];
            ub=[MaxHR,MaxTemperature/MaxHR,MaxTime,MaxHR,MaxTemperature/MaxHR,MaxTime,1,MaxTime,MaxTime,MaxPressure,MaxTime,MaxTime];
            A=[0,1,1,0,1,1,0,0,0,0,0,0];
            b=MaxTime;
            
            w=[str2double(app.w1.Value),str2double(app.w2.Value),str2double(app.w3.Value)];
            
            options = optimoptions('surrogateopt','MaxFunctionEvaluations',str2double(app.Niter.Value));
           
            [Inputs,~]=surrogateopt(@(x) ObjectiveFunction(app,x,w,MaxTime),lb,ub,[1,2,3,4,5,6,8,9,11,12],A,b,[],[],options);
            
            duration=Inputs(2)+Inputs(3)+Inputs(5)+Inputs(6);
            
            %% New Navid ->
            cycle{1}.Inputs=[Inputs(1:6),0.01,1,duration-1,1,1,duration-1];
            cycle{2}.Inputs=[Inputs(1:6),0.01,1,duration-1,MaxPressure,1,duration-1];
            cycle{3}.Inputs=[Inputs(1:6),1,1,duration-1,MaxPressure,1,duration-1];
            cycle{4}.Inputs=[Inputs(1:6),1,1,duration-1,1,1,duration-1];
            OPTCycle.Inputs=Inputs;
            
            [OPTCycle.Cost]=ObjectiveFunction(app,Inputs,w,MaxTime);
            [cycle{1}.Cost]=ObjectiveFunction(app, cycle{1}.Inputs,w,MaxTime);
            [cycle{2}.Cost]=ObjectiveFunction(app, cycle{2}.Inputs,w,MaxTime);
            [cycle{3}.Cost]=ObjectiveFunction(app, cycle{3}.Inputs,w,MaxTime);
            [cycle{4}.Cost]=ObjectiveFunction(app, cycle{4}.Inputs,w,MaxTime);
            
            for i=1:4
                if OPTCycle.Cost>cycle{i}.Cost
                    OPTCycle.Inputs=cycle{i}.Inputs;
                    OPTCycle.Cost=cycle{i}.Cost;
                end               
            end           
            Inputs=OPTCycle.Inputs;
            %% New Navid <-
            
            
            app.OptTemperatureRamp1.Value=num2str(Inputs(1));
            app.OptTemperatureRampDur1.Value=num2str(Inputs(2));
            app.OptTemperatureDwell1.Value=num2str(Inputs(3));
            app.OptTemperatureRamp2.Value=num2str(Inputs(4));
            app.OptTemperatureRampDur2.Value=num2str(Inputs(5));
            app.OptTemperatureDwell2.Value=num2str(Inputs(6));
            app.OptVacuumPressure.Value=num2str(Inputs(7)*100);
            app.OptVacuumStart.Value=num2str(Inputs(8));
            app.OptVacuumDur.Value=num2str(Inputs(9));
            app.OptAutoclavePressure.Value=num2str(Inputs(10)*100);
            app.OptAutoclaveStart.Value=num2str(Inputs(11));
            app.OptAutoclaveDur.Value=num2str(Inputs(12));
            
            Outputs=MachineLearning(app,Inputs);
            
            app.OptimalMaxFiberVolumeFraction.Value=num2str(Outputs(1));
            app.OptimalEffectiveBondlinePorosity.Value=num2str(Outputs(4));
            app.OptimalTotalTime.Value=num2str(Outputs(6));
            
            Inputs(7)=Inputs(7)*100;
            Inputs(10)=Inputs(10)*100;
            
            [Opt_Time,Opt_Temperature,Opt_Vac,Opt_Auto]=CureCycle(app,Inputs);
            
            [DoC_PR,~]=Hexcel_8552(app,Opt_Time,Opt_Temperature.Profile);
            [DoC_AD,~]=Henkel_9658(app,Opt_Time,Opt_Temperature.Profile);
            
            app.OptimalCureDegree_PR.Value=num2str(DoC_PR(end));
            app.OptimalCureDegree_AD.Value=num2str(DoC_AD(end));
            
            app.Fig5.reset;
            
            app.Fig5.XLim=[0,max(Opt_Time)/60];
            app.Fig5.YLim=[0,max(Opt_Temperature.Profile)];
            plot (app.Fig5,Opt_Time/60,Opt_Temperature.Profile,"LineWidth",2,"Color",'k')
            ylabel(app.Fig5,'Temperature [C]','FontSize', 14);
            xlabel(app.Fig5,'Time [min]','FontSize', 14);
            app.Fig5.XGrid='on'
            app.Fig5.YGrid='on'
            app.Fig5.YLim=[20,max(Opt_Temperature.Profile)+10];
            app.Fig5.XLim=[0,max(Opt_Time)/60];
            
            app.Fig6.reset;

            app.Fig6.XLim=[0,max(Opt_Time)/60];
            app.Fig6.YLim=[0,400];
            plot(app.Fig6,Opt_Time/60,Opt_Vac.Profile/1000,'LineWidth',2,'Color','k')
            hold(app.Fig6, 'on')
            ylabel(app.Fig6,'Pressure [kPa]','FontSize', 14);
            xlabel(app.Fig6,'Time [min]','FontSize', 14);
            plot(app.Fig6,Opt_Time/60,Opt_Auto.Profile/1000,'LineWidth',2,'Color','b')
            legend(app.Fig6, 'Vacuum Bag','Autoclave','Location','northeast','FontSize', 12)
            app.Fig6.XLim=[0,max(Opt_Time)/60];
            app.Fig6.YLim=[0,400];
            app.Fig6.XGrid='on'
            app.Fig6.YGrid='on'
            hold(app.Fig6, 'off')


        end

        % Value changed function: ClearButton_Opt
        function ClearButton_OptValueChanged(app, event)
            value = app.ClearButton_Opt.Value;
            
            cla(app.Fig5);
            cla(app.Fig6);
            
            app.OptTemperatureRamp1.Value='';
            app.OptTemperatureRampDur1.Value='';
            app.OptTemperatureDwell1.Value='';
            app.OptTemperatureRamp2.Value='';
            app.OptTemperatureRampDur2.Value='';
            app.OptTemperatureDwell2.Value='';
            app.OptVacuumPressure.Value='';
            app.OptVacuumStart.Value='';
            app.OptVacuumDur.Value='';
            app.OptAutoclavePressure.Value='';
            app.OptAutoclaveStart.Value='';
            app.OptAutoclaveDur.Value='';
            
            app.OptimalMaxFiberVolumeFraction.Value='';
            app.OptimalEffectiveBondlinePorosity.Value='';
            app.OptimalTotalTime.Value='';
            app.OptimalCureDegree_PR.Value='';
            app.OptimalCureDegree_AD.Value='';
            
        end

        % Value changed function: ClearButton_Inps
        function ClearButton_InpsValueChanged(app, event)
            value = app.ClearButton_Inps.Value;
            cla(app.Fig1);
            cla(app.Fig2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.Fig2 = uiaxes(app.InputsTab);
            title(app.Fig2, 'Pressure Profiles')
            xlabel(app.Fig2, 'Time [min]')
            ylabel(app.Fig2, 'Pressure [kPa]')
            zlabel(app.Fig2, 'Z')
            app.Fig2.XTick = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240];
            app.Fig2.YTick = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250 260 270 280 290 300 310 320 330 340 350];
            app.Fig1 = uiaxes(app.InputsTab);
            title(app.Fig1, 'Temperature Profile')
            xlabel(app.Fig1, 'Time [min]')
            ylabel(app.Fig1, 'Temperature [C]')
            zlabel(app.Fig1, 'Z')
            app.Fig1.XLim = [0 240];
            app.Fig1.YLim = [0 300];
            app.PlotButton = uibutton(app.InputsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MaterialsPanel = uipanel(app.InputsTab);
            app.MaterialsPanel.Title = 'Materials';
            app.PrepregFacesheetsLabel = uilabel(app.MaterialsPanel);
            app.PrepregFacesheetsLabel.Text = 'Prepreg Facesheets';
            app.HexcelHexPly8552SLabel = uilabel(app.MaterialsPanel);
            app.HexcelHexPly8552SLabel.Text = 'Hexcel HexPly 8552S';
            app.AdhesiveFilmLabel = uilabel(app.MaterialsPanel);
            app.AdhesiveFilmLabel.Text = 'Adhesive Film';
            app.HenkelLoctiteEA9658AEROLabel = uilabel(app.MaterialsPanel);
            app.HenkelLoctiteEA9658AEROLabel.Text = 'Henkel Loctite EA 9658 AERO';
            app.HoneycombCoreLabel = uilabel(app.MaterialsPanel);
            app.HoneycombCoreLabel.Text = 'Honeycomb Core';
            app.NomexhoneycombcoreLabel = uilabel(app.MaterialsPanel);
            app.NomexhoneycombcoreLabel.Text = 'Nomex honeycomb core';
            app.TemperatureProfileSpecificationPanel = uipanel(app.InputsTab);
            app.TemperatureProfileSpecificationPanel.Title = 'Temperature Profile Specification';
            app.TemperatureRampCminEditFieldLabel = uilabel(app.TemperatureProfileSpecificationPanel);
            app.TemperatureRampCminEditFieldLabel.Text = '(1) Temperature Ramp [C/min]';
            app.AHR1 = uieditfield(app.TemperatureProfileSpecificationPanel, 'text');
            app.AHR1.Value = '2';
            app.TemperatureRampDurationminLabel = uilabel(app.TemperatureProfileSpecificationPanel);
            app.TemperatureRampDurationminLabel.Text = '(1) Temperature Ramp Duration [min]';
            app.AHRT1 = uieditfield(app.TemperatureProfileSpecificationPanel, 'text');
            app.AHRT1.Value = '40';
            app.TemperatureDwellDurationminLabel = uilabel(app.TemperatureProfileSpecificationPanel);
            app.TemperatureDwellDurationminLabel.Text = '(1) Temperature Dwell Duration [min]';
            app.AHD1 = uieditfield(app.TemperatureProfileSpecificationPanel, 'text');
            app.AHD1.Value = '60';
            app.TemperatureRampCminLabel = uilabel(app.TemperatureProfileSpecificationPanel);
            app.TemperatureRampCminLabel.Text = '(2) Temperature Ramp [C/min]';
            app.AHR2 = uieditfield(app.TemperatureProfileSpecificationPanel, 'text');
            app.AHR2.Value = '2';
            app.TemperatureRampDurationminLabel_2 = uilabel(app.TemperatureProfileSpecificationPanel);
            app.TemperatureRampDurationminLabel_2.Text = '(2) Temperature Ramp Duration [min]';
            app.AHRT2 = uieditfield(app.TemperatureProfileSpecificationPanel, 'text');
            app.AHRT2.Value = '30';
            app.TemperatureDwellDurationminLabel_2 = uilabel(app.TemperatureProfileSpecificationPanel);
            app.TemperatureDwellDurationminLabel_2.Text = '(2) Temperature Dwell Duration [min]';
            app.AHD2 = uieditfield(app.TemperatureProfileSpecificationPanel, 'text');
            app.AHD2.Value = '120';
            app.Slider = uislider(app.TemperatureProfileSpecificationPanel);
            app.Slider.Limits = [1 2];
            app.Slider.MajorTicks = [1 2];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [1 2];
            app.Slider.Value = 2;
            app.SliderLabel = uilabel(app.TemperatureProfileSpecificationPanel);
            app.SliderLabel.Text = 'Slider';
            app.AutoclaveSpecificationPanel = uipanel(app.InputsTab);
            app.AutoclaveSpecificationPanel.Title = 'Autoclave Specification';
            app.AP = uieditfield(app.AutoclaveSpecificationPanel, 'text');
            app.AP.Value = '300';
            app.AutoclavePressurekPaEditFieldLabel = uilabel(app.AutoclaveSpecificationPanel);
            app.AutoclavePressurekPaEditFieldLabel.Text = 'Autoclave Pressure [kPa]';
            app.AAT = uieditfield(app.AutoclaveSpecificationPanel, 'text');
            app.AAT.Value = '5';
            app.AutoclaveAppliedTimeminEditFieldLabel = uilabel(app.AutoclaveSpecificationPanel);
            app.AutoclaveAppliedTimeminEditFieldLabel.Text = 'Autoclave Applied Time [min]';
            app.AD = uieditfield(app.AutoclaveSpecificationPanel, 'text');
            app.AD.Value = '100';
            app.AutoclaveDurationminEditFieldLabel = uilabel(app.AutoclaveSpecificationPanel);
            app.AutoclaveDurationminEditFieldLabel.Text = 'Autoclave Duration [min]';
            app.VacuumBagSpecificationPanel = uipanel(app.InputsTab);
            app.VacuumBagSpecificationPanel.Title = 'Vacuum Bag Specification';
            app.VP = uieditfield(app.VacuumBagSpecificationPanel, 'text');
            app.VP.Value = '50';
            app.VacuumPressurekPaEditFieldLabel = uilabel(app.VacuumBagSpecificationPanel);
            app.VacuumPressurekPaEditFieldLabel.Text = 'Vacuum Pressure [kPa]';
            app.VAT = uieditfield(app.VacuumBagSpecificationPanel, 'text');
            app.VAT.Value = '10';
            app.VacuumAppliedTimeminEditFieldLabel = uilabel(app.VacuumBagSpecificationPanel);
            app.VacuumAppliedTimeminEditFieldLabel.Text = 'Vacuum Applied Time [min]';
            app.VD = uieditfield(app.VacuumBagSpecificationPanel, 'text');
            app.VD.Value = '80';
            app.VacuumDurationminEditFieldLabel = uilabel(app.VacuumBagSpecificationPanel);
            app.VacuumDurationminEditFieldLabel.Text = 'Vacuum Duration [min]';
            app.ClearButton_Inps = uibutton(app.InputsTab, 'state');
            app.ClearButton_Inps.ValueChangedFcn = createCallbackFcn(app, @ClearButton_InpsValueChanged, true);
            app.ClearButton_Inps.Text = 'Clear';
            app.UDATLabel = uilabel(app.InputsTab);
            app.UDATLabel.Text = 'UDAT';
            app.PredictionTab = uitab(app.TabGroup);
            app.PredictionTab.Title = 'Prediction';
            app.Fig3 = uiaxes(app.PredictionTab);
            title(app.Fig3, 'Viscosity/Degree of Cure (Adhesive)')
            xlabel(app.Fig3, 'X')
            ylabel(app.Fig3, 'Y')
            zlabel(app.Fig3, 'Z')
            app.Fig4 = uiaxes(app.PredictionTab);
            title(app.Fig4, 'Viscosity/Degree of Cure (Prepreg)')
            xlabel(app.Fig4, 'X')
            ylabel(app.Fig4, 'Y')
            zlabel(app.Fig4, 'Z')
            app.StartSimulationButton = uibutton(app.PredictionTab, 'state');
            app.StartSimulationButton.ValueChangedFcn = createCallbackFcn(app, @StartSimulationButtonValueChanged, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.MaxFiberVolumeFractionEditFieldLabel = uilabel(app.PredictionTab);
            app.MaxFiberVolumeFractionEditFieldLabel.Text = 'Max. Fiber Volume Fraction';
            app.MaxFiberVolumeFraction = uieditfield(app.PredictionTab, 'text');
            app.MaxFiberVolumeFraction.Editable = 'off';
            app.AdhesivePorosityEditFieldLabel = uilabel(app.PredictionTab);
            app.AdhesivePorosityEditFieldLabel.Text = 'Adhesive Porosity';
            app.AdhesivePorosity = uieditfield(app.PredictionTab, 'text');
            app.AdhesivePorosity.Editable = 'off';
            app.PrepregPorosityEditFieldLabel = uilabel(app.PredictionTab);
            app.PrepregPorosityEditFieldLabel.Text = 'Prepreg Porosity';
            app.PrepregPorosity = uieditfield(app.PredictionTab, 'text');
            app.PrepregPorosity.Editable = 'off';
            app.EffectiveBondlinePorosityEditFieldLabel = uilabel(app.PredictionTab);
            app.EffectiveBondlinePorosityEditFieldLabel.Text = 'Effective Bond-line Porosity';
            app.EffectiveBondlinePorosity = uieditfield(app.PredictionTab, 'text');
            app.EffectiveBondlinePorosity.Editable = 'off';
            app.PRVolumeEditFieldLabel = uilabel(app.PredictionTab);
            app.PRVolumeEditFieldLabel.Text = 'PR Volume';
            app.PRVolumeEditField = uieditfield(app.PredictionTab, 'text');
            app.PRVolumeEditField.Editable = 'off';
            app.TotalTimeEditFieldLabel = uilabel(app.PredictionTab);
            app.TotalTimeEditFieldLabel.Text = 'Total Time';
            app.TotalTimeEditField = uieditfield(app.PredictionTab, 'text');
            app.TotalTimeEditField.Editable = 'off';
            app.ClearButton = uibutton(app.PredictionTab, 'state');
            app.ClearButton.ValueChangedFcn = createCallbackFcn(app, @ClearButtonValueChanged, true);
            app.ClearButton.Text = 'Clear';
            app.UDATLabel_2 = uilabel(app.PredictionTab);
            app.UDATLabel_2.Text = 'UDAT';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.Fig5 = uiaxes(app.OptimizationTab);
            title(app.Fig5, 'Optimal Temperature Profile')
            xlabel(app.Fig5, 'Time [min]')
            ylabel(app.Fig5, 'Temperature [^oC]')
            zlabel(app.Fig5, 'Z')
            app.Fig6 = uiaxes(app.OptimizationTab);
            title(app.Fig6, 'Optimal Pressure Profile')
            xlabel(app.Fig6, 'Time [min]')
            ylabel(app.Fig6, 'Pressure [kPa]')
            zlabel(app.Fig6, 'Z')
            app.FacilityConstraintPanel = uipanel(app.OptimizationTab);
            app.FacilityConstraintPanel.Title = 'Facility Constraint';
            app.MaxautoclavepressurekPaLabel = uilabel(app.FacilityConstraintPanel);
            app.MaxautoclavepressurekPaLabel.Text = '(1) Max autoclave pressure [kPa]';
            app.MaxAutPressure = uieditfield(app.FacilityConstraintPanel, 'text');
            app.MaxAutPressure.Value = '350';
            app.MaxautoclavetemperatureCLabel = uilabel(app.FacilityConstraintPanel);
            app.MaxautoclavetemperatureCLabel.Text = '(2) Max autoclave temperature [C]';
            app.MaxAutTemperature = uieditfield(app.FacilityConstraintPanel, 'text');
            app.MaxAutTemperature.Value = '180';
            app.MaxheatrateCminLabel = uilabel(app.FacilityConstraintPanel);
            app.MaxheatrateCminLabel.Text = '(2) Max heat rate [C/min]';
            app.MaxHeatRate = uieditfield(app.FacilityConstraintPanel, 'text');
            app.MaxHeatRate.Value = '10';
            app.StartOptimizationButton = uibutton(app.OptimizationTab, 'state');
            app.StartOptimizationButton.ValueChangedFcn = createCallbackFcn(app, @StartOptimizationButtonValueChanged, true);
            app.StartOptimizationButton.Text = 'Start Optimization';
            app.ClearButton_Opt = uibutton(app.OptimizationTab, 'state');
            app.ClearButton_Opt.ValueChangedFcn = createCallbackFcn(app, @ClearButton_OptValueChanged, true);
            app.ClearButton_Opt.Text = 'Clear';
            app.OptimalOutputsPanel = uipanel(app.OptimizationTab);
            app.OptimalOutputsPanel.Title = 'Optimal Outputs';
            app.EffectiveBondlinePorosityLabel = uilabel(app.OptimalOutputsPanel);
            app.EffectiveBondlinePorosityLabel.Text = 'Effective Bond-line Porosity (%)';
            app.OptimalEffectiveBondlinePorosity = uieditfield(app.OptimalOutputsPanel, 'text');
            app.OptimalEffectiveBondlinePorosity.Editable = 'off';
            app.MaxFiberVolumeFractionLabel = uilabel(app.OptimalOutputsPanel);
            app.MaxFiberVolumeFractionLabel.Text = 'Max. Fiber Volume Fraction (%)';
            app.OptimalMaxFiberVolumeFraction = uieditfield(app.OptimalOutputsPanel, 'text');
            app.OptimalMaxFiberVolumeFraction.Editable = 'off';
            app.TotalTimeEditField_2Label = uilabel(app.OptimalOutputsPanel);
            app.TotalTimeEditField_2Label.Text = 'Total Time';
            app.OptimalTotalTime = uieditfield(app.OptimalOutputsPanel, 'text');
            app.OptimalTotalTime.Editable = 'off';
            app.CureDegreePrepregLabel = uilabel(app.OptimalOutputsPanel);
            app.CureDegreePrepregLabel.Text = 'Cure Degree Prepreg';
            app.OptimalCureDegree_PR = uieditfield(app.OptimalOutputsPanel, 'text');
            app.OptimalCureDegree_PR.Editable = 'off';
            app.CureDegreeAdhesiveLabel = uilabel(app.OptimalOutputsPanel);
            app.CureDegreeAdhesiveLabel.Text = 'Cure Degree Adhesive';
            app.OptimalCureDegree_AD = uieditfield(app.OptimalOutputsPanel, 'text');
            app.OptimalCureDegree_AD.Editable = 'off';
            app.OptimalCureCyclePanel = uipanel(app.OptimizationTab);
            app.OptimalCureCyclePanel.Title = 'Optimal Cure Cycle';
            app.TemperatureRampCminLabel_2 = uilabel(app.OptimalCureCyclePanel);
            app.TemperatureRampCminLabel_2.Text = '(1) Temperature Ramp [C/min]';
            app.OptTemperatureRamp1 = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptTemperatureRamp1.Editable = 'off';
            app.TemperatureRampDurationminLabel_3 = uilabel(app.OptimalCureCyclePanel);
            app.TemperatureRampDurationminLabel_3.Text = '(1) Temperature Ramp Duration [min]';
            app.OptTemperatureRampDur1 = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptTemperatureRampDur1.Editable = 'off';
            app.TemperatureDwellDurationminLabel_3 = uilabel(app.OptimalCureCyclePanel);
            app.TemperatureDwellDurationminLabel_3.Text = '(1) Temperature Dwell Duration [min]';
            app.OptTemperatureDwell1 = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptTemperatureDwell1.Editable = 'off';
            app.TemperatureRampCminLabel_3 = uilabel(app.OptimalCureCyclePanel);
            app.TemperatureRampCminLabel_3.Text = '(2) Temperature Ramp [C/min]';
            app.OptTemperatureRamp2 = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptTemperatureRamp2.Editable = 'off';
            app.TemperatureRampDurationminLabel_7 = uilabel(app.OptimalCureCyclePanel);
            app.TemperatureRampDurationminLabel_7.Text = '(2) Temperature Ramp Duration [min]';
            app.OptTemperatureRampDur2 = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptTemperatureRampDur2.Editable = 'off';
            app.TemperatureDwellDurationminLabel_5 = uilabel(app.OptimalCureCyclePanel);
            app.TemperatureDwellDurationminLabel_5.Text = '(2) Temperature Dwell Duration [min]';
            app.OptTemperatureDwell2 = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptTemperatureDwell2.Editable = 'off';
            app.VacuumPressurekPaLabel = uilabel(app.OptimalCureCyclePanel);
            app.VacuumPressurekPaLabel.Text = 'Vacuum Pressure [kPa]';
            app.OptVacuumPressure = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptVacuumPressure.Editable = 'off';
            app.VacuumStartTimeminLabel = uilabel(app.OptimalCureCyclePanel);
            app.VacuumStartTimeminLabel.Text = 'Vacuum Start Time [min]';
            app.OptVacuumStart = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptVacuumStart.Editable = 'off';
            app.VacuumDurationminLabel = uilabel(app.OptimalCureCyclePanel);
            app.VacuumDurationminLabel.Text = 'Vacuum Duration [min]';
            app.OptVacuumDur = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptVacuumDur.Editable = 'off';
            app.AutoclavePressurekPaLabel = uilabel(app.OptimalCureCyclePanel);
            app.AutoclavePressurekPaLabel.Text = 'Autoclave Pressure [kPa]';
            app.OptAutoclavePressure = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptAutoclavePressure.Editable = 'off';
            app.AutoclaveStartTimeminLabel = uilabel(app.OptimalCureCyclePanel);
            app.AutoclaveStartTimeminLabel.Text = 'Autoclave Start Time [min]';
            app.OptAutoclaveStart = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptAutoclaveStart.Editable = 'off';
            app.AutoclaveDurationminLabel = uilabel(app.OptimalCureCyclePanel);
            app.AutoclaveDurationminLabel.Text = 'Autoclave Duration [min]';
            app.OptAutoclaveDur = uieditfield(app.OptimalCureCyclePanel, 'text');
            app.OptAutoclaveDur.Editable = 'off';
            app.CostFunctionWeightsPanel = uipanel(app.OptimizationTab);
            app.CostFunctionWeightsPanel.Title = 'Cost Function Weights';
            app.FacesheetConsolidationLabel = uilabel(app.CostFunctionWeightsPanel);
            app.FacesheetConsolidationLabel.Text = 'Facesheet Consolidation';
            app.w1 = uieditfield(app.CostFunctionWeightsPanel, 'text');
            app.w1.Value = '1';
            app.BondlinePorosityLabel = uilabel(app.CostFunctionWeightsPanel);
            app.BondlinePorosityLabel.Text = 'Bond-line Porosity';
            app.w2 = uieditfield(app.CostFunctionWeightsPanel, 'text');
            app.w2.Value = '0';
            app.TotalCuringTimeLabel = uilabel(app.CostFunctionWeightsPanel);
            app.TotalCuringTimeLabel.Text = 'Total Curing Time';
            app.w3 = uieditfield(app.CostFunctionWeightsPanel, 'text');
            app.w3.Value = '0';
            app.UDATLabel_3 = uilabel(app.OptimizationTab);
            app.UDATLabel_3.Text = 'UDAT';
            app.NumberofIterationsLabel = uilabel(app.OptimizationTab);
            app.NumberofIterationsLabel.Text = 'Number of Iterations';
            app.Niter = uieditfield(app.OptimizationTab, 'text');
            app.Niter.Value = '200';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UD_autoclave_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end